classdef DeviceConfigs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        devConfigsUIFigure              matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        devConfigCtrlPanel              matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LoadDeviceConfigsButton         matlab.ui.control.Button
        SaveDeviceConfigsButton         matlab.ui.control.Button
        ConnectALLDevicesButton         matlab.ui.control.StateButton
        PrevDeviceButton                matlab.ui.control.Button
        NextDeviceButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BatteryPackTab                  matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        CellIDsPanel                    matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        DeleteCellsButton               matlab.ui.control.Button
        AddCellsButton                  matlab.ui.control.Button
        CellConfigurationPanel          matlab.ui.container.Panel
        CellStackArrangementDropDownLabel  matlab.ui.control.Label
        cellConfigDropDown              matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        EditTableButton_BattPack        matlab.ui.control.StateButton
        SaveChangesButton               matlab.ui.control.Button
        DiscardChangesButton            matlab.ui.control.Button
        DataRecordingPanel              matlab.ui.container.Panel
        SampleTimesSpinnerLabel         matlab.ui.control.Label
        SampleTimesSpinner              matlab.ui.control.Spinner
        DataToRecordPanel               matlab.ui.container.Panel
        VoltageCheckBox                 matlab.ui.control.CheckBox
        CurrentCheckBox                 matlab.ui.control.CheckBox
        CapacityCheckBox                matlab.ui.control.CheckBox
        AmbientTemperatureCheckBox_2    matlab.ui.control.CheckBox
        StateOfChargeSOCCheckBox        matlab.ui.control.CheckBox
        CellTemperatureCheckBox         matlab.ui.control.CheckBox
        SelectAllCheckBox               matlab.ui.control.CheckBox
        SaveFileNameEditFieldLabel      matlab.ui.control.Label
        SaveFileNameEditField           matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel     matlab.ui.control.Label
        SaveDirectoryEditField          matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        PurposeofTestTextAreaLabel      matlab.ui.control.Label
        PurposeofTestTextArea           matlab.ui.control.TextArea
        EStopMCUTab                     matlab.ui.container.Tab
        EStopGrid                       matlab.ui.container.GridLayout
        SerialPortPanel_EStop           matlab.ui.container.Panel
        SerialPortGrid_EStop            matlab.ui.container.GridLayout
        COMPortDropDown_Label           matlab.ui.control.Label
        COMPortDropDown_EStop           matlab.ui.control.DropDown
        BaudRateDropDown_2Label         matlab.ui.control.Label
        BaudRateDropDown_EStop          matlab.ui.control.DropDown
        TimeoutEditField_2Label         matlab.ui.control.Label
        TimeoutEditField_EStop          matlab.ui.control.EditField
        EmergencyButton_EStop           matlab.ui.control.StateButton
        ResponseTextAreaLabel           matlab.ui.control.Label
        ResponseTextArea_EStop          matlab.ui.control.TextArea
        ByteOrderDropDownLabel          matlab.ui.control.Label
        ByteOrderDropDown_EStop         matlab.ui.control.DropDown
        TerminatorLabel                 matlab.ui.control.Label
        TerminatorDropDown_EStop        matlab.ui.control.DropDown
        StopBitsDropDownLabel_2         matlab.ui.control.Label
        StopBitsDropDown_EStop          matlab.ui.control.DropDown
        ConnectionMethodPanel_EStop     matlab.ui.container.Panel
        ConnectEStopButton              matlab.ui.control.StateButton
        ConnectionmethodDropDown_2Label  matlab.ui.control.Label
        ConnectionmethodDropDown_EStop  matlab.ui.control.DropDown
        ConfigureNextDeviceButton       matlab.ui.control.Button
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        SerialPortPanel_PSU             matlab.ui.container.Panel
        SerialPortGrid_PSU              matlab.ui.container.GridLayout
        ClearAlarmCodesButton_PSU       matlab.ui.control.Button
        TerminalOutputsButtonGroup_PSU  matlab.ui.container.ButtonGroup
        ONButton_PSU                    matlab.ui.control.ToggleButton
        OFFButton_PSU                   matlab.ui.control.ToggleButton
        GetDeviceDetailsButton_PSU      matlab.ui.control.Button
        COMPortDropDownLabel            matlab.ui.control.Label
        COMPortDropDown_PSU             matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        BaudRateDropDown_PSU            matlab.ui.control.DropDown
        TimeoutEditFieldLabel           matlab.ui.control.Label
        TimeoutEditField_PSU            matlab.ui.control.EditField
        ByteOrderDropDownLabel_2        matlab.ui.control.Label
        ByteOrderDropDown_PSU           matlab.ui.control.DropDown
        TerminatorLabel_2               matlab.ui.control.Label
        TerminatorDropDown_PSU          matlab.ui.control.DropDown
        ResponseTextAreaLabel_2         matlab.ui.control.Label
        ResponseTextArea_PSU            matlab.ui.control.TextArea
        SetControlOwnerListBoxLabel     matlab.ui.control.Label
        SetControlOwnerListBox_PSU      matlab.ui.control.ListBox
        StopBitsDropDownLabel           matlab.ui.control.Label
        StopBitsDropDown_PSU            matlab.ui.control.DropDown
        LANCOMMPanel                    matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        PSUDropDownLabel                matlab.ui.control.Label
        PSUDropDown                     matlab.ui.control.DropDown
        ConnectonMethodGrid_PSU         matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel   matlab.ui.control.Label
        ConnectionmethodDropDown_PSU    matlab.ui.control.DropDown
        ConnectPSUButton                matlab.ui.control.StateButton
        SelfTestCheckBox_PSU            matlab.ui.control.CheckBox
        ElectronicLoadTab               matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GridLayout4_2                   matlab.ui.container.GridLayout
        SerialPortPanel_ELoad           matlab.ui.container.Panel
        SerialPortGrid_ELoad            matlab.ui.container.GridLayout
        ClearAlarmCodesButton_ELoad     matlab.ui.control.Button
        TerminalOutputsButtonGroup_ELoad  matlab.ui.container.ButtonGroup
        ONButton_ELoad                  matlab.ui.control.ToggleButton
        OFFButton_ELoad                 matlab.ui.control.ToggleButton
        GetDeviceDetailsButton_ELoad    matlab.ui.control.Button
        COMPortDropDownLabel_2          matlab.ui.control.Label
        COMPortDropDown_ELoad           matlab.ui.control.DropDown
        BaudRateDropDownLabel_2         matlab.ui.control.Label
        BaudRateDropDown_ELoad          matlab.ui.control.DropDown
        TimeoutEditFieldLabel_2         matlab.ui.control.Label
        TimeoutEditField_ELoad          matlab.ui.control.EditField
        ByteOrderDropDownLabel_3        matlab.ui.control.Label
        ByteOrderDropDown_ELoad         matlab.ui.control.DropDown
        TerminatorLabel_3               matlab.ui.control.Label
        TerminatorDropDown_ELoad        matlab.ui.control.DropDown
        ResponseTextAreaLabel_3         matlab.ui.control.Label
        ResponseTextArea_ELoad          matlab.ui.control.TextArea
        SetControlOwnerListBoxLabel_2   matlab.ui.control.Label
        SetControlOwnerListBox_ELoad    matlab.ui.control.ListBox
        StopBitsDropDownLabel_3         matlab.ui.control.Label
        StopBitsDropDown_ELoad          matlab.ui.control.DropDown
        GPIBPanel                       matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        GridLayout6_2                   matlab.ui.container.GridLayout
        ELoadLabel                      matlab.ui.control.Label
        ELoadDropDown                   matlab.ui.control.DropDown
        ConnectonMethodGrid_ELoad       matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel_2  matlab.ui.control.Label
        ConnectionmethodDropDown_ELoad  matlab.ui.control.DropDown
        ConnectELoadButton              matlab.ui.control.StateButton
        SelfTestCheckBox_ELoad          matlab.ui.control.CheckBox
        TemperatureModuleTab            matlab.ui.container.Tab
        BalancerTab                     matlab.ui.container.Tab
        RelayMCUTab                     matlab.ui.container.Tab
        ContextMenu                     matlab.ui.container.ContextMenu
        ClearMenu                       matlab.ui.container.Menu
        CellTreeContextMenu             matlab.ui.container.ContextMenu
        AddCellPackMenu                 matlab.ui.container.Menu
        ExpandMenu                      matlab.ui.container.Menu
        ExpandMenu_2                    matlab.ui.container.Menu
        ExpandAllMenu                   matlab.ui.container.Menu
        CollapseMenu                    matlab.ui.container.Menu
        CollapseMenu_2                  matlab.ui.container.Menu
        CollapseAllMenu                 matlab.ui.container.Menu
        DeselectAllMenu                 matlab.ui.container.Menu
        CellDeleteContextMenu           matlab.ui.container.ContextMenu
        DeleteCellPackMenu              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %         serialPortUpdateTimer % Timer that updates the drop down every once in a while
        %         serialPortUpdateInterval = 100; % ms
        mainApp % Parent App (Battery Manager)
%         dialog_addCell % Dialog App for adding new cells
        
        backupFileName = "devConfigs_BackupData"; % File name for file that contains the previous entries for the component on the app.
        
        appDataLoc % Path of the GUI data directory
        
        nextTab % Variable to store the state (or tab) for the "Next Tab" button
        prevTab % Variable to store the state (or tab) for the "Previous Tab" button
        
        batteryParam % Variable to store single cell parameters
        packParam  % Variable to store pack parameters
    end
    
    properties (Access = public)
        % Objects for the connectable devices
        eStop
        psu
        eload
        thermo
        dialog_addCells
        psuName % Property to store the PSU device being used in this program. This is so the right class file can be used
        eloadName % Property to store the eload device being used in this program. This is so the right class file can be used
        
        battDataLoc % Path for common Battery data directory
        
        eStopArg % Arguments from the GUI used to initialize the EStop object
        psuArg % Arguments from the GUI used to initialize the PSU object
        eloadArg  % Arguments from the GUI used to initialize the ELoad object
        thermoArg % Arguments from the GUI used to initialize the Thermo Module object
        balArg % Arguments from the GUI used to initialize the Balancer object
        relayArg % Arguments from the GUI used to initialize the Relay MCU object
        
        newCellsAdded % A cell Array of added data

        val
    end
    
    methods (Access = private)
        function updateSerialPortDropdowns(app)
            serialAvail = serialportlist("available"); % Get all serial ports currently available
            list = ["Select COM Port", serialAvail];
            app.COMPortDropDown_PSU.Items = list;
            app.COMPortDropDown_EStop.Items = list;
        end
        
        function updateSerialPortDropdown(~, dropdown)
            serialAvail = serialportlist("available"); % Get all serial ports currently available
            list = ["Select COM Port", serialAvail];
            dropdown.Items = list;
        end
        
        function updateTextArea(~, textArea, response)
            %updateTextArea Update Response Text Area
            cellArrayText = textArea.Value;
            if strcmp(cellArrayText(end), '') && length(cellArrayText) == 1
                textArea.Value = string(response) + newline + newline;
            else
                cellArrayText{end+1, 1} = char(response); % + newline;
                textArea.Value = cellArrayText;
                %                 textArea.Value = [textArea.Value; char(response); ""];
            end
        end
        
        
        function saveAllArgs(app)
            %saveAllArgs Saves all the arguments in components likely to
            %remain the same so user does not have to enter it every time
            
            % List of variables
            psuArgs = app.psuArg;
            
            save(app.appDataLoc + app.backupFileName, ...
                "psuArgs")
            
        end
        
        function updateCellNodes(app) 
            app.Tree.Children.delete; % Remove all nodes first
            
            if app.cellConfigDropDown.Value == "single"
                
                % First level nodes
                uniqueChemList = sort(unique(app.batteryParam.chemistry));
                len = length(uniqueChemList);
                chems = cell(1, len);
                cellNodes = cell(len, height(app.batteryParam));
                
                for i = 1:length(uniqueChemList)
                    chems{i} = uitreenode(app.Tree,'Text', uniqueChemList(i));
                    chems{i}.ContextMenu = app.CellTreeContextMenu;
                    
%                     cmChildren = chems{i}.ContextMenu.Children;
%                     for j = 1:length(cmChildren)
%                         if cmChildren(j) == app.RemoveCellPackMenu
%                             cmChildren(j).Enable = false;
%                         end
%                     end
                    
                    
                    % Second level nodes
                    rows = (app.batteryParam.chemistry==uniqueChemList(i));
                    cellList = sort(app.batteryParam(rows, "chemistry").Properties.RowNames);
                    
                    for j = 1:length(cellList)
                        cellNodes{i, j} = uitreenode(chems{i},'Text',cellList{j});
%                         cellNodes{i, j}.ContextMenu = app.CellDeleteContextMenu;
                    end
                    chems{i}.expand();
                end
            else
                % First level nodes
                uniqueChemList = sort(unique(app.packParam.chemistry));
                len = length(uniqueChemList);
                chems = cell(1, len); 
                cells = cell(len, height(app.packParam));
                
                for i = 1:length(uniqueChemList)
                    chems{i} = uitreenode(app.Tree,'Text', uniqueChemList(i));
                    chems{i}.ContextMenu = app.CellTreeContextMenu;

                    % Second level nodes
                    rows = (app.packParam.chemistry==uniqueChemList(i));
                    cellList = sort(app.packParam(rows, "chemistry").Properties.RowNames);
                    
                    for j = 1:length(cellList)
                        cells{i, j} = uitreenode(chems{i},'Text', cellList{j});
%                         cells{i, j}.ContextMenu = app.CellDeleteContextMenu;
                    end
                    chems{i}.expand();
                end
            end
            
        end
        
    end
    
    methods (Access = public)
        
        function enable_EStops(app, action)
            %Enable_EStops Enables or disables all EStops in the program
            app.EmergencyButton_EStop.Enable = action;
            if (~isempty(app.mainApp) && isvalid(app.mainApp)) % if the argument passed is not empty and is a valid handle
                app.mainApp.EmergencyButton.Enable = action;
            end
        end
        
        function disconnectAllDevices(app)
            
            % Disable PSU Output Terminals and Disconnect COMM
            if (~isempty(app.psu) && isvalid(app.psu)) % if the argument passed is not empty and is a valid handle
                app.OFFButton_PSU.Value = true;
                response = disconnect(app.psu) + newline;
                app.updateTextArea(app.ResponseTextArea_PSU, response);
                
                app.ConnectPSUButton.Value = 0;
                ConnectPSUButtonValueChanged(app, []);
            end
            
            % Disable ELoad Output Terminals and Disconnect COMM
            if (~isempty(app.eload) && isvalid(app.eload)) % if the argument passed is not empty and is a valid handle
                app.OFFButton_ELoad.Value = true;
                response = Disconnect(app.eload) + newline;
                app.updateTextArea(app.ResponseTextArea_ELoad, response);
                
                app.ConnectELoadButton.Value = 0;
                ConnectELoadButtonValueChanged(app, []);
            end


            % Disable EStop Last( Disconnect COMM)
            if (~isempty(app.eStop) && isvalid(app.eStop)) % if the argument passed is not empty and is a valid handle
                app.ConnectEStopButton.Value = 0;
                ConnectEStopButtonValueChanged(app, []);
            end
                        
        end
        
        
        function toggleEStop(app, value)
            writeline(app.eStop, string(double(value)));
            response = "";
            
            try
                % Wait for data to be received
                while(app.eStop.NumBytesAvailable == 0)
                end
                
                while (app.eStop.NumBytesAvailable ~= 0)
                    response = response + readline(app.eStop);
                end
                
                app.updateTextArea(app.ResponseTextArea_EStop, response + newline);
            catch MEX
                app.updateTextArea(app.ResponseTextArea_EStop, "No Response.");
                rethrow(MEX);
            end
        end
        
        function settings = getTestSettings(app)
            settings = struct;
            
            settings.readPeriod = app.SampleTimesSpinner.Value;
            settings.cellConfig = app.cellConfigDropDown.Value;
            settings.dataLocation = app.battDataLoc;
        end
        
        function updateBattPackParams(app, config, params)
            if config == "single"
%                 prevHeight = height(app.batteryParam);
                rowNames_old = string(app.batteryParam.Row);
                rowNames_In = string(params.Row);
                
                % check to see if there are any old cells being updated
                addNew_Ind = []; updateOld_Ind = [];
                for i = 1:length(rowNames_In)
                    if ~ismember(rowNames_In(i), rowNames_old)
                        addNew_Ind(i) = i;
                    else
                        updateOld_Ind(i) = i;
                    end
                end
                
                if ~isempty(addNew_Ind)
                    app.batteryParam = [app.batteryParam;...
                                         params(rowNames_In(addNew_Ind), :)];
                end
                
                if ~isempty(updateOld_Ind)
                    app.batteryParam(rowNames_In(updateOld_Ind), :) ...
                        = params(rowNames_In(updateOld_Ind), :);
                end
                
                
                batteryParam = app.batteryParam; %#ok<*ADPROPLC>
                save(app.battDataLoc + "007BatteryParam", 'batteryParam');
                
            else
%                 prevHeight = height(app.packParam);
                rowNames_old = string(app.packParam.Row);
                rowNames_In = string(params.Row);
                
                % check to see if there are any old cells being updated
                addNew_Ind = []; updateOld_Ind = [];
                for i = 1:length(rowNames_In)
                    if ~ismember(rowNames_In(i), rowNames_old)
                        addNew_Ind(i) = i;
                    else
                        updateOld_Ind(i) = i;
                    end
                end
                
                if ~isempty(addNew_Ind)
                    app.packParam = [app.packParam;...
                                         params(rowNames_In(addNew_Ind), :)];
                end
                
                if ~isempty(updateOld_Ind)
                    app.packParam(rowNames_In(updateOld_Ind), :) ...
                        = params(rowNames_In(updateOld_Ind), :);
                end

                packParam = app.packParam; %#ok<*ADPROPLC> 
                save(app.battDataLoc + "007PackParam", "packParam")
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            %% Gets the full path for the current file and changes directory
            currFilePath = mfilename('fullpath');
            % Seperates the path directory and the filename
            [path, ~, ~] = fileparts(currFilePath);
            
            app.appDataLoc = path + "\GUI_Data\";
            
            newStr = extractBetween(app.appDataLoc,"",...
                           "03DataGen","Boundaries","inclusive");
            app.battDataLoc = newStr + "\01CommonDataForBattery\";
            
            % Update all serial port dropdowns to serial ports current
            % available
            load(app.battDataLoc + "007BatteryParam", 'batteryParam');
            load(app.battDataLoc + "007PackParam", "packParam")
            app.batteryParam = batteryParam;  
            app.packParam = packParam;  
            app.updateSerialPortDropdowns();
            
            % Load Previous Session settings
            if isfile(app.appDataLoc + app.backupFileName + ".mat")
                load(app.appDataLoc + app.backupFileName);
                
                if exist('eStopArgs', 'var')
                    app.eStopArg = eStopArgs;
                end
                if exist('psuArgs', 'var')
                    app.psuArg = psuArgs;
                    if isfield(app.psuArg, 'devName')
                        app.psuName = app.psuArg.devName;
                    end
                end
                if exist('eloadArgs', 'var')
                    app.eloadArg = eloadArgs;
                    if isfield(app.psuArg, 'devName')
                        app.eloadName = app.eloadArg.devName;
                    end
                end
                if exist('thermoArgs', 'var')
                    app.thermoArg = thermoArgs;
                end
                if exist('balArgs', 'var')
                    app.balArg = balArgs;
                end
                if exist('relayArgs', 'var')
                    app.relayArg = relayArgs;
                end
            end
            
            % Update Cell Information in Cell Configuration
            app.updateCellNodes();
            
            % Set the previous and Next Tabs based on the default selected
            % tab
            app.prevTab = app.RelayMCUTab;
            app.nextTab = app.EStopMCUTab;
            
            if nargin == 2
                main_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(main_App) && isvalid(main_App))
                    app.mainApp = main_App;
                end
            end
        end

        % Close request function: devConfigsUIFigure
        function devConfigsUIFigureCloseRequest(app, event)
            % if the argument passed is not empty and is a valid handle
            if (~isempty(app.mainApp) && isvalid(app.mainApp))
                app.devConfigsUIFigure.Visible = false;
            else
                app.disconnectAllDevices();
%                 app.saveAllArgs();
                delete(app)
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_EStop
        function ConnectionmethodDropDown_EStopValueChanged(app, event)
            value = app.ConnectionmethodDropDown_EStop.Value;
            switch value
                case ""
                    % Disable all components if "Don't Connect" is selected
                    components = app.SerialPortGrid_EStop.Children;
                    for i = 1:length(components)
                        components(i).Enable = false;
                    end
                    app.enable_EStops(false);
                    app.ConnectEStopButton.Enable = false;
                    
%                     % Reenable the components to choose a connection method
%                     components = app.ConnectionMethodPanel_EStop;
%                     for i = 1:length(components)
%                         components(i).Enable = true;
%                     end
                    return;
                case "serial"
                    % update the drop down with a list of available serial
                    % com ports
                    app.updateSerialPortDropdown(app.COMPortDropDown_EStop);
                    
                    components = app.SerialPortGrid_EStop.Children;
                    for i = 1:length(components)
                        components(i).Enable = true;
                    end
                    app.ConnectEStopButton.Enable = true;
                    app.EmergencyButton_EStop.Enable = false;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.eStopArg)
                        if ismember(app.eStopArg.COMPort,...
                                app.COMPortDropDown_EStop.Items)
                            app.COMPortDropDown_EStop.Value = upper(app.eStopArg.COMPort);
                        end
                        app.BaudRateDropDown_EStop.Value = string(app.eStopArg.baudRate);
                        app.StopBitsDropDown_EStop.Value = string(app.eStopArg.stopBits);
                        app.ByteOrderDropDown_EStop.Value = app.eStopArg.byteOrder;
                        app.TerminatorDropDown_EStop.Value = app.eStopArg.terminator;
                        app.TimeoutEditField_EStop.Value = string(app.eStopArg.timeout);
                    end
                    
                otherwise
                    return;
            end

        end

        % Value changed function: EmergencyButton_EStop
        function EmergencyButton_EStopValueChanged(app, event)
            value = app.EmergencyButton_EStop.Value;
            
            app.toggleEStop(value);
            
            app.mainApp.EmergencyButton.Value = value;
            
            if value == 1
                app.mainApp.updateChargeStatus(app.mainApp.chargeStatus.eStop);
            else
                app.mainApp.updateChargeStatus(app.mainApp.chargeStatus.idle);
            end
            
        end

        % Value changed function: ConnectEStopButton
        function ConnectEStopButtonValueChanged(app, event)
            value = app.ConnectEStopButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_EStop.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_EStop.Value);
                        baudRate = str2double(app.BaudRateDropDown_EStop.Value);
                        byteOrder = app.ByteOrderDropDown_EStop.Value;
                        stopBits = str2double(app.StopBitsDropDown_EStop.Value);
                        terminator = app.TerminatorDropDown_EStop.Value;
                        timeout = str2double(app.TimeoutEditField_EStop.Value);
                        
                        app.eStopArg.type = app.ConnectionmethodDropDown_EStop.Value;
                        app.eStopArg.COMPort = COMPort;
                        app.eStopArg.baudRate = baudRate;
                        app.eStopArg.byteOrder = byteOrder;
                        app.eStopArg.stopBits = stopBits;
                        app.eStopArg.terminator = terminator;
                        app.eStopArg.timeout = timeout;
                        
                        eStopArgs = app.eStopArg;
                        save(app.appDataLoc + app.backupFileName, 'eStopArgs', '-append')
                        
                        app.eStop = serialport(COMPort, baudRate);
                        app.eStop.StopBits = stopBits;
                        app.eStop.ByteOrder = byteOrder;
                        configureTerminator(app.eStop,terminator)
                        app.eStop.Timeout = timeout;
                        app.COMPortDropDown_EStop.Enable = false;

                end
                
                app.enable_EStops(true);
                
                app.updateTextArea(app.ResponseTextArea_EStop, 'E-Stop Connected!');
                
            else
                switch app.ConnectionmethodDropDown_EStop.Value
                    case "serial"
                        app.eStop = [];
                        app.COMPortDropDown_EStop.Enable = true;
                end
                app.enable_EStops(false);
                app.updateTextArea(app.ResponseTextArea_EStop, 'E-Stop Disconnected!');
            end
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.updateTextArea(app.ResponseTextArea_EStop, "");
        end

        % Value changed function: PSUDropDown
        function PSUDropDownValueChanged(app, event)
            value = app.PSUDropDown.Value;
            if value == ""
%                 app.psuName = value;
                app.ConnectPSUButton.Enable = false;
                
            else
                app.psuName = value;
                app.ConnectPSUButton.Enable = true;
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_PSU
        function ConnectionmethodDropDown_PSUValueChanged(app, event)
            value = app.ConnectionmethodDropDown_PSU.Value;
            switch value
                case ""
                    % Disable [SerialPortGrid_PSU]'s components if "Don't Connect" is selected
                    components = app.SerialPortGrid_PSU.Children;
                    for i = 1:length(components)
                        if components(i) == app.TerminalOutputsButtonGroup_PSU
                            buttons = app.TerminalOutputsButtonGroup_PSU.Children;
                            for j = 1:length(buttons)
                                buttons(j).Enable = false;
                            end
                        else
                            components(i).Enable = false;
                        end
                    end
                    app.ConnectPSUButton.Enable = false;
                    return;
                    
                case "serial"
                    app.updateSerialPortDropdown(app.COMPortDropDown_PSU);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    components = app.SerialPortGrid_PSU.Children;
                    for i = 1:length(components)
                        if (components(i) ~= app.TerminalOutputsButtonGroup_PSU) ...
                                && (components(i) ~= app.GetDeviceDetailsButton_PSU)...
                                && (components(i) ~= app.ClearAlarmCodesButton_PSU)...
                                && (components(i) ~= app.SetControlOwnerListBox_PSU)
                            components(i).Enable = true;
                        end
                    end
                    app.ConnectPSUButton.Enable = true;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.psuArg)
                        if ismember(app.psuArg.COMPort,...
                                app.COMPortDropDown_PSU.Items)
                            app.COMPortDropDown_PSU.Value = upper(app.psuArg.COMPort);
                        end
                        app.BaudRateDropDown_PSU.Value = string(app.psuArg.baudRate);
                        app.StopBitsDropDown_PSU.Value = string(app.psuArg.stopBits);
                        app.ByteOrderDropDown_PSU.Value = app.psuArg.byteOrder;
                        app.TerminatorDropDown_PSU.Value = app.psuArg.terminator;
                        app.TimeoutEditField_PSU.Value = string(app.psuArg.timeout);
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectPSUButton
        function ConnectPSUButtonValueChanged(app, event)
            value = app.ConnectPSUButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_PSU.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_PSU.Value);
                        baudRate = str2double(app.BaudRateDropDown_PSU.Value);
                        byteOrder = app.ByteOrderDropDown_PSU.Value;
                        stopBits = str2double(app.StopBitsDropDown_PSU.Value);
                        terminator = app.TerminatorDropDown_PSU.Value;
                        timeout = str2double(app.TimeoutEditField_PSU.Value);
                        
                        if COMPort == "Select COM Port"
                            uialert(1, "Cannot connect! Please choose a COM Port!", "Connection Error")
                            return;
                        end
                        
                        if app.PSUDropDown.Value == ""
                            uialert(1, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                            return;
                        end
                        
                        
                        app.psuArg.type = app.ConnectionmethodDropDown_PSU.Value;
                        app.psuArg.devName = app.PSUDropDown.Value;
                        app.psuArg.COMPort = COMPort;
                        app.psuArg.baudRate = baudRate;
                        app.psuArg.byteOrder = byteOrder;
                        app.psuArg.stopBits = stopBits;
                        app.psuArg.terminator = terminator;
                        app.psuArg.timeout = timeout;
                        
                        psuArgs = app.psuArg;
                        save(app.appDataLoc + app.backupFileName, 'psuArgs', '-append')
                        
                        if app.SelfTestCheckBox_PSU.Value == 1
                            switch upper(app.psuName)
                                case "APM"
                                    app.psu = APM_PSU(COMPort, "baudRate", baudRate);
                                    app.psu.stopBits = stopBits;
                                    app.psu.byteOrder = byteOrder;
                                    app.psu.terminator = terminator;
                                    app.psu.timeout = timeout;
                                    app.updateTextArea(app.ResponseTextArea_PSU, "PSU Connected!");
                                otherwise
                                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                                    return;
                            end
                            
                            app.ClearAlarmCodesButton_PSU.Enable = true;
                            app.ONButton_PSU.Enable = true;
                            app.OFFButton_PSU.Enable = true;
                            app.GetDeviceDetailsButton_PSU.Enable = true;
                            app.SetControlOwnerListBox_PSU.Enable = true;
                        end
                end
                
                app.COMPortDropDown_PSU.Enable = false;
                
                
            else
                switch app.ConnectionmethodDropDown_PSU.Value
                    case "serial"
                        if app.SelfTestCheckBox_PSU.Value == true
                            response = disconnect(app.psu);
                            app.psu = [];
                            app.updateTextArea(app.ResponseTextArea_PSU, ...
                                string(response + newline + newline + "PSU Disconnected!"));
                        end
                        
                        app.COMPortDropDown_PSU.Enable = true;
                end
                app.OFFButton_PSU.Value = true;
                app.ClearAlarmCodesButton_PSU.Enable = false;
                app.ONButton_PSU.Enable = false;
                app.OFFButton_PSU.Enable = false;
                app.GetDeviceDetailsButton_PSU.Enable = false;
                app.SetControlOwnerListBox_PSU.Enable = false;


            end
        end

        % Value changed function: SetControlOwnerListBox_PSU
        function SetControlOwnerListBox_PSUValueChanged(app, event)
            value = app.SetControlOwnerListBox_PSU.Value;
            switch upper(app.psuName)
                case "APM"
                    response = setSystCtrl(app.psu, value);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            % Update Response Text Area
            app.updateTextArea(app.ResponseTextArea_PSU, response);

            
        end

        % Selection changed function: TerminalOutputsButtonGroup_PSU
        function TerminalOutputsButtonGroup_PSUSelectionChanged(app, event)
            selectedButton = app.TerminalOutputsButtonGroup_PSU.SelectedObject;
            switch upper(app.psuName)
                case "APM"
                    if selectedButton == app.ONButton_PSU
                        response = connect(app.psu);
                    else
                        response = disconnect(app.psu);
                    end
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            
            updateTextArea(app, app.ResponseTextArea_PSU, response);
        end

        % Button pushed function: GetDeviceDetailsButton_PSU
        function GetDeviceDetailsButton_PSUPushed(app, event)
            
            switch upper(app.psuName)
                case "APM"
                    response = WhoAmI(app.psu);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            updateTextArea(app, app.ResponseTextArea_PSU, response);
        end

        % Value changed function: COMPortDropDown_PSU
        function COMPortDropDown_PSUValueChanged(app, event)
%             value = app.COMPortDropDown_PSU.Value;
            app.updateSerialPortDropdown(app.COMPortDropDown_PSU);
        end

        % Button pushed function: ClearAlarmCodesButton_PSU
        function ClearAlarmCodesButton_PSUPushed(app, event)
            reply = ClearAlarmCode(app.psu);
            if reply == "Alarm Cleared"
                app.updateTextArea(app.ResponseTextArea_PSU, "Errors/Alarms Cleared.");
            else
                app.updateTextArea(app.ResponseTextArea_PSU, "Errors/Alarms NOT Cleared - " + reply);
            end
        end

        % Value changed function: ELoadDropDown
        function ELoadDropDownValueChanged(app, event)
            value = app.ELoadDropDown.Value;
            if value == ""
%                 app.eloadName = value;
                app.ConnectELoadButton.Enable = false;
                
            else
                app.eloadName = value;
                app.ConnectELoadButton.Enable = true;
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_ELoad
        function ConnectionmethodDropDown_ELoadValueChanged(app, event)
            value = app.ConnectionmethodDropDown_ELoad.Value;
            
            switch value
                case ""
                    % Disable [SerialPortGrid_ELoad]'s components if "Don't Connect" is selected
                    components = app.SerialPortGrid_ELoad.Children;
                    for i = 1:length(components)
                        if components(i) == app.TerminalOutputsButtonGroup_ELoad
                            buttons = app.TerminalOutputsButtonGroup_ELoad.Children;
                            for j = 1:length(buttons)
                                buttons(j).Enable = false;
                            end
                        else
                            components(i).Enable = false;
                        end
                    end
                    app.ConnectELoadButton.Enable = false;
                    return;
                    
                case "serial"
                    app.updateSerialPortDropdown(app.COMPortDropDown_ELoad);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    components = app.SerialPortGrid_ELoad.Children;
                    for i = 1:length(components)
                        if (components(i) ~= app.TerminalOutputsButtonGroup_ELoad) ...
                                && (components(i) ~= app.GetDeviceDetailsButton_ELoad)...
                                && (components(i) ~= app.ClearAlarmCodesButton_ELoad)...
                                && (components(i) ~= app.SetControlOwnerListBox_ELoad)
                            components(i).Enable = true;
                        end
                    end
                    app.ConnectELoadButton.Enable = true;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.eloadArg)
                        if ismember(app.eloadArg.COMPort,...
                                app.COMPortDropDown_ELoad.Items)
                            app.COMPortDropDown_ELoad.Value = upper(app.psuArg.COMPort);
                        end
                        app.BaudRateDropDown_ELoad.Value = string(app.eloadArg.baudRate);
                        app.StopBitsDropDown_ELoad.Value = string(app.eloadArg.stopBits);
                        app.ByteOrderDropDown_ELoad.Value = app.eloadArg.byteOrder;
                        app.TerminatorDropDown_ELoad.Value = app.eloadArg.terminator;
                        app.TimeoutEditField_ELoad.Value = string(app.eloadArg.timeout);
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectELoadButton
        function ConnectELoadButtonValueChanged(app, event)
            value = app.ConnectELoadButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_ELoad.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_ELoad.Value);
                        baudRate = str2double(app.BaudRateDropDown_ELoad.Value);
                        byteOrder = app.ByteOrderDropDown_ELoad.Value;
                        stopBits = str2double(app.StopBitsDropDown_ELoad.Value);
                        terminator = app.TerminatorDropDown_ELoad.Value;
                        timeout = str2double(app.TimeoutEditField_ELoad.Value);
                        
                        if COMPort == "Select COM Port"
                            uialert(1, "Cannot connect! Please choose a COM Port!", "Connection Error")
                            return;
                        end
                        
                        if app.ELoadDropDown.Value == ""
                            uialert(1, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                            return;
                        end
                        
                        app.eloadArg.type = app.ConnectionmethodDropDown_ELoad.Value;
                        app.eloadArg.devName = app.ELoadDropDown.Value;
                        app.eloadArg.COMPort = COMPort;
                        app.eloadArg.baudRate = baudRate;
                        app.eloadArg.byteOrder = byteOrder;
                        app.eloadArg.stopBits = stopBits;
                        app.eloadArg.terminator = terminator;
                        app.eloadArg.timeout = timeout;
                        
                        eloadArgs = app.eloadArg;
                        save(app.appDataLoc + app.backupFileName, 'eloadArgs', '-append')
                        
                        if app.SelfTestCheckBox_ELoad.Value == 1
                            switch upper(app.eloadName)
                                case "ARRAY"
                                    app.eload = Array_ELoad(COMPort, "baudRate", baudRate);
                                    app.eload.stopBits = stopBits;
                                    app.eload.byteOrder = byteOrder;
                                    app.eload.terminator = terminator;
                                    app.eload.timeout = timeout;
                                    app.updateTextArea(app.ResponseTextArea_ELoad, "E-Load Connected!");
                                otherwise
                                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                                    return;
                            end
                            
                            app.ClearAlarmCodesButton_ELoad.Enable = true;
                            app.ONButton_ELoad.Enable = true;
                            app.OFFButton_ELoad.Enable = true;
                            app.GetDeviceDetailsButton_ELoad.Enable = true;
                            app.SetControlOwnerListBox_ELoad.Enable = true;
                        end
                end
                
                app.COMPortDropDown_ELoad.Enable = false;
                
                
            else
                switch app.ConnectionmethodDropDown_ELoad.Value
                    case "serial"
                        if app.SelfTestCheckBox_ELoad.Value == true
                            response = Disconnect(app.eload);
                            app.eload = [];
                            app.updateTextArea(app.ResponseTextArea_ELoad, ...
                                string(response + newline + newline + "E-Load Disconnected!"));
                        end
                        
                        app.COMPortDropDown_ELoad.Enable = true;
                end
                app.OFFButton_ELoad.Value = true;
                app.ClearAlarmCodesButton_ELoad.Enable = false;
                app.ONButton_ELoad.Enable = false;
                app.OFFButton_ELoad.Enable = false;
                app.GetDeviceDetailsButton_ELoad.Enable = false;
                app.SetControlOwnerListBox_ELoad.Enable = false;
                
            end
        end

        % Value changed function: SetControlOwnerListBox_ELoad
        function SetControlOwnerListBox_ELoadValueChanged(app, event)
            value = app.SetControlOwnerListBox_ELoad.Value;
            
            switch upper(app.eloadName)
                case "ARRAY"
                    response = SetSystCtrl(app.eload, value);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            % Update Response Text Area
            app.updateTextArea(app.ResponseTextArea_ELoad, response);
        end

        % Selection changed function: 
        % TerminalOutputsButtonGroup_ELoad
        function TerminalOutputsButtonGroup_ELoadSelectionChanged(app, event)
            selectedButton = app.TerminalOutputsButtonGroup_ELoad.SelectedObject;
            
            switch upper(app.eloadName)
                case "ARRAY"
                    if selectedButton == app.ONButton_ELoad
                        response = Connect(app.eload);
                    else
                        response = Disconnect(app.eload);
                    end
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            
            updateTextArea(app, app.ResponseTextArea_ELoad, response);
        end

        % Button pushed function: GetDeviceDetailsButton_ELoad
        function GetDeviceDetailsButton_ELoadPushed(app, event)
            switch upper(app.eloadName)
                case "ARRAY"
                    response = WhoAmI(app.eload);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            updateTextArea(app, app.ResponseTextArea_ELoad, response);
        end

        % Value changed function: COMPortDropDown_ELoad
        function COMPortDropDown_ELoadValueChanged(app, event)
%             value = app.COMPortDropDown_ELoad.Value;
            app.updateSerialPortDropdown(app.COMPortDropDown_ELoad);

        end

        % Button pushed function: ClearAlarmCodesButton_ELoad
        function ClearAlarmCodesButton_ELoadPushed(app, event)
            reply = ClearAlarmCode(app.eload);
            if reply == "Alarm Cleared"
                app.updateTextArea(app.ResponseTextArea_ELoad, "Errors/Alarms Cleared.");
            else
                app.updateTextArea(app.ResponseTextArea_ELoad, "Errors/Alarms NOT Cleared - " + reply);
            end
        end

        % Value changed function: ConnectALLDevicesButton
        function ConnectALLDevicesButtonValueChanged(app, event)
            value = app.ConnectALLDevicesButton.Value;
            if value == 1
                % Connect EStop
                app.ConnectEStopButton.Value = 1;
                ConnectEStopButtonValueChanged(app, []);
                
                % Connect Power Supply
                app.SelfTestCheckBox_PSU.Value = 0;
                app.ConnectPSUButton.Value = 1;
                ConnectPSUButtonValueChanged(app, [])
                
                % Connect Electronic Load
                app.SelfTestCheckBox_ELoad.Value = 0;
                app.ConnectELoadButton.Value = 1;
                ConnectELoadButtonValueChanged(app, [])
            else
                app.disconnectAllDevices();
            end
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            if value == 1
                % Select all check box components except for
                % [SelectAllCheckBox]
                parent = app.SelectAllCheckBox.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if (components(i) ~= app.SelectAllCheckBox)
                        components(i).Value = true;
                    end
                end
            else
                % Deselect all check box components except for
                % [SelectAllCheckBox]
                parent = app.SelectAllCheckBox.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if (components(i) ~= app.SelectAllCheckBox)
                        components(i).Value = false;
                    end
                end
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.BatteryPackTab
                    app.prevTab = app.RelayMCUTab;
                    app.nextTab = app.EStopMCUTab;
                case app.EStopMCUTab
                    app.prevTab = app.BatteryPackTab;
                    app.nextTab = app.PowerSupplyTab;
                case app.PowerSupplyTab
                    app.prevTab = app.EStopMCUTab;
                    app.nextTab = app.ElectronicLoadTab;
                    app.PSUDropDown.Value = app.psuName;
                    if isfield(app.psuArg, 'type')
                        app.ConnectionmethodDropDown_PSU.Value = app.psuArg.type;
                        ConnectionmethodDropDown_PSUValueChanged(app, []);
                    end
                case app.ElectronicLoadTab
                    app.prevTab = app.PowerSupplyTab;
                    app.nextTab = app.TemperatureModuleTab;
                    app.ELoadDropDown.Value = app.eloadName;
                    if isfield(app.eloadArg, 'type')
                        app.ConnectionmethodDropDown_ELoad.Value = app.eloadArg.type;
                        ConnectionmethodDropDown_ELoadValueChanged(app, []);
                    end
                case app.TemperatureModuleTab
                    app.prevTab = app.ElectronicLoadTab;
                    app.nextTab = app.BalancerTab;
                case app.BalancerTab
                    app.prevTab = app.BalancerTab;
                    app.nextTab = app.RelayMCUTab;
                case app.RelayMCUTab
                    app.prevTab = app.BalancerTab;
                    app.nextTab = app.BatteryPackTab;
            end
        end

        % Button pushed function: PrevDeviceButton
        function PrevDeviceButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.prevTab;
            TabGroupSelectionChanged(app, []);
        end

        % Button pushed function: NextDeviceButton
        function NextDeviceButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.nextTab;
            TabGroupSelectionChanged(app, []);
        end

        % Value changed function: cellConfigDropDown
        function cellConfigDropDownValueChanged(app, event)
            app.updateCellNodes();           
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if (~isempty(selectedNodes))
                tree = app.Tree.Children;
                treeNodeTexts = repmat("", 1, length(tree));
                for i = 1:length(tree)
                    treeNodeTexts(i) = string(tree(i).Text);
                end
                
                selectedNodesTexts = repmat("", 1, length(selectedNodes));
                rows2Rmv = [];
                for i = 1:length(selectedNodes)
                    selectedNodesTexts(i) = string(selectedNodes(i).Text);
                    if ismember(selectedNodesTexts(i), treeNodeTexts)
                        rows2Rmv(end+1) = i;
                    end
                end
                
                selectedNodesTexts(rows2Rmv) = []; % Remove the text of selections that are category names instead of cell names
                selectedNodesTexts = sort(selectedNodesTexts, "ascend");
%                 app.val = selectedNodesTexts;
                
                % if there are no proper selections
                if isempty(selectedNodesTexts)
                    return;
                end
                
                if app.cellConfigDropDown.Value == "single"
                    app.UITable.ColumnName = ...
                        ["Chem" ,"Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)", "Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.RowName = selectedNodesTexts;
                    dispTable = app.batteryParam(selectedNodesTexts, :);
                    app.UITable.Data = dispTable; 
                else
                    app.UITable.ColumnName = ...
                        ["Chem" , "Cells", " Stack Shape","Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)","Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
%                     tWidth = repmat({'auto'}, 1, width(app.packParam));
%                     tWidth{2} = 100;
                    
                    app.UITable.RowName = selectedNodesTexts;
                    dispTable = app.packParam(selectedNodesTexts, :);
                    app.UITable.Data = dispTable; 
                    
                end
                
            else
                if app.cellConfigDropDown.Value == "single"
                    app.UITable.ColumnName = ...
                        ["Chem" ,"Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)", "Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.Data = [];
                else
                    app.UITable.ColumnName = ...
                        ["Chem" , "Cells", " Stack Shape","Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)","Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.Data = [];
                end
            end
        end

        % Menu selected function: CollapseAllMenu
        function CollapseAllMenuSelected(app, event)
            app.Tree.collapse();
        end

        % Menu selected function: ExpandAllMenu
        function ExpandAllMenuSelected(app, event)
            app.Tree.expand();
        end

        % Menu selected function: DeselectAllMenu
        function DeselectAllMenuSelected(app, event)
            app.Tree.SelectedNodes = [];
            TreeSelectionChanged(app, []);
        end

        % Value changed function: EditTableButton_BattPack
        function EditTableButton_BattPackValueChanged(app, event)
            value = app.EditTableButton_BattPack.Value;
            if value == 1
                app.UITable.ColumnEditable = true;
            else
                app.UITable.ColumnEditable = false;
            end

        end

        % Menu selected function: ExpandMenu_2
        function ExpandMenu_2Selected(app, event)
            selectedNode = app.Tree.SelectedNodes;
            selectedNode.expand();
        end

        % Menu selected function: CollapseMenu_2
        function CollapseMenu_2Selected(app, event)
            selectedNode = app.Tree.SelectedNodes;
            selectedNode.collapse();
        end

        % Callback function: DeleteCellPackMenu, DeleteCellsButton
        function DeleteCellsButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if (~isempty(selectedNodes))
                tree = app.Tree.Children;
                treeNodeTexts = repmat("", 1, length(tree));
                for i = 1:length(tree)
                    treeNodeTexts(i) = string(tree(i).Text);
                end
                
                selectedNodesTexts = repmat("", 1, length(selectedNodes));
                rows2Rmv = [];
                for i = 1:length(selectedNodes)
                    selectedNodesTexts(i) = string(selectedNodes(i).Text);
                    if ismember(selectedNodesTexts(i), treeNodeTexts)
                        rows2Rmv(end+1) = i;
                    end
                end
                
                selectedNodesTexts(rows2Rmv) = []; % Remove the text of selections that are category names instead of cell names
                selectedNodesTexts = sort(selectedNodesTexts, "ascend");
                %                 app.val = selectedNodesTexts;
                
                % if there are no proper selections
                if isempty(selectedNodesTexts)
                    return;
                end
                
                
                if app.cellConfigDropDown.Value == "single"
                    app.batteryParam(selectedNodesTexts, :) = [];
                    batteryParam = app.batteryParam; %#ok<*ADPROPLC>
                    save(app.battDataLoc + "007BatteryParam", 'batteryParam');
                else
                    app.packParam(selectedNodesTexts, :) = [];
                    packParam = app.packParam; %#ok<*ADPROPLC>
                    save(app.battDataLoc + "007packParam", 'packParam');
                end
                app.updateCellNodes();
            end
            
        end

        % Button pushed function: AddCellsButton
        function AddCellsButtonPushed(app, event)
            
            app.dialog_addCells = Dialog_AddCell(app, app.batteryParam, app.packParam);
            uiwait(app.dialog_addCells.AddCellsPackUIFigure);
            app.updateCellNodes();
        end

        % Button pushed function: DiscardChangesButton
        function DiscardChangesButtonPushed(app, event)
            app.DiscardChangesButton.Enable = "off";
            app.SaveChangesButton.Enable = "off";
            app.EditTableButton_BattPack.Value = false;
            TreeSelectionChanged(app, []);
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            config = app.cellConfigDropDown.Value;
            params = app.UITable.Data;
%             rowNames = app.UITable.RowName
            app.updateBattPackParams(config, params);
            app.DiscardChangesButton.Enable = "off";
            app.SaveChangesButton.Enable = "off";
            app.EditTableButton_BattPack.Value = false;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            app.DiscardChangesButton.Enable = "on";
            app.SaveChangesButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.devConfigsUIFigure = uifigure('Visible', 'off');
            app.devConfigsUIFigure.Name = 'Device Configurations';
            app.devConfigsUIFigure.CloseRequestFcn = createCallbackFcn(app, @devConfigsUIFigureCloseRequest, true);
            app.devConfigsUIFigure.Tag = 'devConfigs';
            app.GridLayout2 = uigridlayout(app.devConfigsUIFigure);
            app.devConfigCtrlPanel = uipanel(app.GridLayout2);
            app.GridLayout = uigridlayout(app.devConfigCtrlPanel);
            app.LoadDeviceConfigsButton = uibutton(app.GridLayout, 'push');
            app.LoadDeviceConfigsButton.Icon = 'download.png';
            app.LoadDeviceConfigsButton.Text = {'Load '; 'Device Configs'};
            app.SaveDeviceConfigsButton = uibutton(app.GridLayout, 'push');
            app.SaveDeviceConfigsButton.Icon = 'save.png';
            app.SaveDeviceConfigsButton.Text = {'Save '; 'Device Configs'};
            app.ConnectALLDevicesButton = uibutton(app.GridLayout, 'state');
            app.ConnectALLDevicesButton.ValueChangedFcn = createCallbackFcn(app, @ConnectALLDevicesButtonValueChanged, true);
            app.ConnectALLDevicesButton.Text = 'Connect ALL Devices';
            app.PrevDeviceButton = uibutton(app.GridLayout, 'push');
            app.PrevDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @PrevDeviceButtonPushed, true);
            app.PrevDeviceButton.Icon = 'left-arrow.png';
            app.PrevDeviceButton.Text = 'Prev Device';
            app.NextDeviceButton = uibutton(app.GridLayout, 'push');
            app.NextDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @NextDeviceButtonPushed, true);
            app.NextDeviceButton.Icon = 'next.png';
            app.NextDeviceButton.Text = 'Next Device';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup.Tag = 'tab1';
            app.BatteryPackTab = uitab(app.TabGroup);
            app.BatteryPackTab.Title = 'Battery Pack';
            app.GridLayout7 = uigridlayout(app.BatteryPackTab);
            app.CellIDsPanel = uipanel(app.GridLayout7);
            app.CellIDsPanel.Title = 'Cell IDs';
            app.Tree = uitree(app.CellIDsPanel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'List of cells availble to system'};
            app.DeleteCellsButton = uibutton(app.CellIDsPanel, 'push');
            app.DeleteCellsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCellsButtonPushed, true);
            app.DeleteCellsButton.Icon = 'minus.png';
            app.DeleteCellsButton.Text = '';
            app.AddCellsButton = uibutton(app.CellIDsPanel, 'push');
            app.AddCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellsButtonPushed, true);
            app.AddCellsButton.Icon = 'plus.png';
            app.AddCellsButton.Text = '';
            app.CellConfigurationPanel = uipanel(app.GridLayout7);
            app.CellConfigurationPanel.Title = 'Cell Configuration';
            app.CellStackArrangementDropDownLabel = uilabel(app.CellConfigurationPanel);
            app.CellStackArrangementDropDownLabel.Text = 'Cell Stack Arrangement';
            app.cellConfigDropDown = uidropdown(app.CellConfigurationPanel);
            app.cellConfigDropDown.Items = {'Single Cell', 'Series Stack', 'Parallel Stack', 'Series-Parallel Stack'};
            app.cellConfigDropDown.ItemsData = {'single', 'series', 'parallel', 'ser_par'};
            app.cellConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @cellConfigDropDownValueChanged, true);
            app.cellConfigDropDown.Value = 'single';
            app.UITable = uitable(app.CellConfigurationPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.EditTableButton_BattPack = uibutton(app.CellConfigurationPanel, 'state');
            app.EditTableButton_BattPack.ValueChangedFcn = createCallbackFcn(app, @EditTableButton_BattPackValueChanged, true);
            app.EditTableButton_BattPack.Text = 'Edit Table';
            app.SaveChangesButton = uibutton(app.CellConfigurationPanel, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Enable = 'off';
            app.SaveChangesButton.Text = 'Save Changes';
            app.DiscardChangesButton = uibutton(app.CellConfigurationPanel, 'push');
            app.DiscardChangesButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardChangesButtonPushed, true);
            app.DiscardChangesButton.Enable = 'off';
            app.DiscardChangesButton.Text = 'Discard Changes';
            app.DataRecordingPanel = uipanel(app.GridLayout7);
            app.DataRecordingPanel.Title = 'Data Recording';
            app.SampleTimesSpinnerLabel = uilabel(app.DataRecordingPanel);
            app.SampleTimesSpinnerLabel.Text = 'Sample Time (s)';
            app.SampleTimesSpinner = uispinner(app.DataRecordingPanel);
            app.SampleTimesSpinner.Step = 0.25;
            app.SampleTimesSpinner.Limits = [0.25 3600];
            app.SampleTimesSpinner.ValueDisplayFormat = '%11.2f';
            app.SampleTimesSpinner.Value = 1;
            app.DataToRecordPanel = uipanel(app.DataRecordingPanel);
            app.DataToRecordPanel.Title = 'Data To Record';
            app.VoltageCheckBox = uicheckbox(app.DataToRecordPanel);
            app.VoltageCheckBox.Text = 'Voltage';
            app.CurrentCheckBox = uicheckbox(app.DataToRecordPanel);
            app.CurrentCheckBox.Text = 'Current';
            app.CapacityCheckBox = uicheckbox(app.DataToRecordPanel);
            app.CapacityCheckBox.Text = 'Capacity';
            app.AmbientTemperatureCheckBox_2 = uicheckbox(app.DataToRecordPanel);
            app.AmbientTemperatureCheckBox_2.Text = 'Ambient Temperature';
            app.StateOfChargeSOCCheckBox = uicheckbox(app.DataToRecordPanel);
            app.StateOfChargeSOCCheckBox.Text = 'State Of Charge (SOC)';
            app.CellTemperatureCheckBox = uicheckbox(app.DataToRecordPanel);
            app.CellTemperatureCheckBox.Text = 'Cell Temperature';
            app.SelectAllCheckBox = uicheckbox(app.DataToRecordPanel);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SaveFileNameEditFieldLabel = uilabel(app.DataRecordingPanel);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name';
            app.SaveFileNameEditField = uieditfield(app.DataRecordingPanel, 'text');
            app.SaveDirectoryEditFieldLabel = uilabel(app.DataRecordingPanel);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.DataRecordingPanel, 'text');
            app.SaveDirectoryEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.DataRecordingPanel, 'push');
            app.BrowseButton.Icon = 'folder.png';
            app.BrowseButton.Text = 'Browse';
            app.PurposeofTestTextAreaLabel = uilabel(app.DataRecordingPanel);
            app.PurposeofTestTextAreaLabel.Text = 'Purpose of Test';
            app.PurposeofTestTextArea = uitextarea(app.DataRecordingPanel);
            app.EStopMCUTab = uitab(app.TabGroup);
            app.EStopMCUTab.Title = 'E-Stop MCU';
            app.EStopMCUTab.Tag = 'tab2';
            app.EStopGrid = uigridlayout(app.EStopMCUTab);
            app.SerialPortPanel_EStop = uipanel(app.EStopGrid);
            app.SerialPortPanel_EStop.Title = 'Serial Port';
            app.SerialPortGrid_EStop = uigridlayout(app.SerialPortPanel_EStop);
            app.COMPortDropDown_Label = uilabel(app.SerialPortGrid_EStop);
            app.COMPortDropDown_Label.Enable = 'off';
            app.COMPortDropDown_Label.Text = 'COM Port';
            app.COMPortDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.COMPortDropDown_EStop.Items = {'Select COM Port'};
            app.COMPortDropDown_EStop.Enable = 'off';
            app.COMPortDropDown_EStop.Value = 'Select COM Port';
            app.BaudRateDropDown_2Label = uilabel(app.SerialPortGrid_EStop);
            app.BaudRateDropDown_2Label.Enable = 'off';
            app.BaudRateDropDown_2Label.Text = 'Baud Rate';
            app.BaudRateDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.BaudRateDropDown_EStop.Items = {'4800', '9600', '19200', '38400', '57600', '115200', '230400', '500000', '1000000'};
            app.BaudRateDropDown_EStop.Enable = 'off';
            app.BaudRateDropDown_EStop.Value = '115200';
            app.TimeoutEditField_2Label = uilabel(app.SerialPortGrid_EStop);
            app.TimeoutEditField_2Label.Enable = 'off';
            app.TimeoutEditField_2Label.Tooltip = {'in milliseconds'};
            app.TimeoutEditField_2Label.Text = 'Timeout';
            app.TimeoutEditField_EStop = uieditfield(app.SerialPortGrid_EStop, 'text');
            app.TimeoutEditField_EStop.Enable = 'off';
            app.TimeoutEditField_EStop.Tooltip = {'in Seconds'};
            app.TimeoutEditField_EStop.Value = '10';
            app.EmergencyButton_EStop = uibutton(app.SerialPortGrid_EStop, 'state');
            app.EmergencyButton_EStop.ValueChangedFcn = createCallbackFcn(app, @EmergencyButton_EStopValueChanged, true);
            app.EmergencyButton_EStop.Enable = 'off';
            app.EmergencyButton_EStop.Icon = 'stop.png';
            app.EmergencyButton_EStop.Text = 'Emergency ';
            app.ResponseTextAreaLabel = uilabel(app.SerialPortGrid_EStop);
            app.ResponseTextAreaLabel.Enable = 'off';
            app.ResponseTextAreaLabel.Text = 'Response';
            app.ResponseTextArea_EStop = uitextarea(app.SerialPortGrid_EStop);
            app.ResponseTextArea_EStop.Enable = 'off';
            app.ByteOrderDropDownLabel = uilabel(app.SerialPortGrid_EStop);
            app.ByteOrderDropDownLabel.Enable = 'off';
            app.ByteOrderDropDownLabel.Text = 'Byte Order';
            app.ByteOrderDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.ByteOrderDropDown_EStop.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_EStop.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_EStop.Enable = 'off';
            app.ByteOrderDropDown_EStop.Value = 'big-endian';
            app.TerminatorLabel = uilabel(app.SerialPortGrid_EStop);
            app.TerminatorLabel.Enable = 'off';
            app.TerminatorLabel.Text = 'Terminator';
            app.TerminatorDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.TerminatorDropDown_EStop.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_EStop.Enable = 'off';
            app.TerminatorDropDown_EStop.Value = 'LF';
            app.StopBitsDropDownLabel_2 = uilabel(app.SerialPortGrid_EStop);
            app.StopBitsDropDownLabel_2.Enable = 'off';
            app.StopBitsDropDownLabel_2.Text = 'Stop Bits';
            app.StopBitsDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.StopBitsDropDown_EStop.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_EStop.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_EStop.Editable = 'on';
            app.StopBitsDropDown_EStop.Enable = 'off';
            app.StopBitsDropDown_EStop.Value = '1';
            app.ConnectionMethodPanel_EStop = uipanel(app.EStopGrid);
            app.ConnectEStopButton = uibutton(app.ConnectionMethodPanel_EStop, 'state');
            app.ConnectEStopButton.ValueChangedFcn = createCallbackFcn(app, @ConnectEStopButtonValueChanged, true);
            app.ConnectEStopButton.Enable = 'off';
            app.ConnectEStopButton.Text = 'Connect E-Stop';
            app.ConnectionmethodDropDown_2Label = uilabel(app.ConnectionMethodPanel_EStop);
            app.ConnectionmethodDropDown_2Label.Text = 'Connection method';
            app.ConnectionmethodDropDown_EStop = uidropdown(app.ConnectionMethodPanel_EStop);
            app.ConnectionmethodDropDown_EStop.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_EStop.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_EStop.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_EStopValueChanged, true);
            app.ConnectionmethodDropDown_EStop.Value = '';
            app.ConfigureNextDeviceButton = uibutton(app.ConnectionMethodPanel_EStop, 'push');
            app.ConfigureNextDeviceButton.Text = 'Configure Next Device';
            app.PowerSupplyTab = uitab(app.TabGroup);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout3 = uigridlayout(app.PowerSupplyTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SerialPortPanel_PSU = uipanel(app.GridLayout4);
            app.SerialPortPanel_PSU.Title = 'SERIAL PORT';
            app.SerialPortGrid_PSU = uigridlayout(app.SerialPortPanel_PSU);
            app.ClearAlarmCodesButton_PSU = uibutton(app.SerialPortGrid_PSU, 'push');
            app.ClearAlarmCodesButton_PSU.ButtonPushedFcn = createCallbackFcn(app, @ClearAlarmCodesButton_PSUPushed, true);
            app.ClearAlarmCodesButton_PSU.Enable = 'off';
            app.ClearAlarmCodesButton_PSU.Text = 'Clear Alarm Codes';
            app.TerminalOutputsButtonGroup_PSU = uibuttongroup(app.SerialPortGrid_PSU);
            app.TerminalOutputsButtonGroup_PSU.SelectionChangedFcn = createCallbackFcn(app, @TerminalOutputsButtonGroup_PSUSelectionChanged, true);
            app.TerminalOutputsButtonGroup_PSU.Tooltip = {'Allow or disallow the PSU from transferring power via terminals'};
            app.TerminalOutputsButtonGroup_PSU.Title = 'Terminal Outputs';
            app.ONButton_PSU = uitogglebutton(app.TerminalOutputsButtonGroup_PSU);
            app.ONButton_PSU.Enable = 'off';
            app.ONButton_PSU.Text = 'ON';
            app.OFFButton_PSU = uitogglebutton(app.TerminalOutputsButtonGroup_PSU);
            app.OFFButton_PSU.Enable = 'off';
            app.OFFButton_PSU.Text = 'OFF';
            app.OFFButton_PSU.Value = true;
            app.GetDeviceDetailsButton_PSU = uibutton(app.SerialPortGrid_PSU, 'push');
            app.GetDeviceDetailsButton_PSU.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceDetailsButton_PSUPushed, true);
            app.GetDeviceDetailsButton_PSU.Enable = 'off';
            app.GetDeviceDetailsButton_PSU.Text = 'Get Device Details';
            app.COMPortDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.COMPortDropDownLabel.Enable = 'off';
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.COMPortDropDown_PSU.Items = {'Select COM Port'};
            app.COMPortDropDown_PSU.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDown_PSUValueChanged, true);
            app.COMPortDropDown_PSU.Enable = 'off';
            app.COMPortDropDown_PSU.Value = 'Select COM Port';
            app.BaudRateDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.BaudRateDropDownLabel.Enable = 'off';
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.BaudRateDropDown_PSU.Items = {'4800', '9600', '19200', '38400', '57600', '115200', '230400', '500000', '1000000'};
            app.BaudRateDropDown_PSU.Enable = 'off';
            app.BaudRateDropDown_PSU.Value = '115200';
            app.TimeoutEditFieldLabel = uilabel(app.SerialPortGrid_PSU);
            app.TimeoutEditFieldLabel.Enable = 'off';
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField_PSU = uieditfield(app.SerialPortGrid_PSU, 'text');
            app.TimeoutEditField_PSU.Enable = 'off';
            app.TimeoutEditField_PSU.Tooltip = {'in Seconds'};
            app.TimeoutEditField_PSU.Value = '10';
            app.ByteOrderDropDownLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.ByteOrderDropDownLabel_2.Enable = 'off';
            app.ByteOrderDropDownLabel_2.Text = 'Byte Order';
            app.ByteOrderDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.ByteOrderDropDown_PSU.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_PSU.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_PSU.Enable = 'off';
            app.ByteOrderDropDown_PSU.Value = 'big-endian';
            app.TerminatorLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.TerminatorLabel_2.Enable = 'off';
            app.TerminatorLabel_2.Text = 'Terminator';
            app.TerminatorDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.TerminatorDropDown_PSU.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_PSU.Enable = 'off';
            app.TerminatorDropDown_PSU.Value = 'LF';
            app.ResponseTextAreaLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.ResponseTextAreaLabel_2.Enable = 'off';
            app.ResponseTextAreaLabel_2.Text = 'Response';
            app.ResponseTextArea_PSU = uitextarea(app.SerialPortGrid_PSU);
            app.ResponseTextArea_PSU.Editable = 'off';
            app.ResponseTextArea_PSU.Enable = 'off';
            app.SetControlOwnerListBoxLabel = uilabel(app.SerialPortGrid_PSU);
            app.SetControlOwnerListBoxLabel.Enable = 'off';
            app.SetControlOwnerListBoxLabel.Text = 'Set Control Owner';
            app.SetControlOwnerListBox_PSU = uilistbox(app.SerialPortGrid_PSU);
            app.SetControlOwnerListBox_PSU.Items = {'Software', 'Local Device'};
            app.SetControlOwnerListBox_PSU.ItemsData = {'remote', 'local'};
            app.SetControlOwnerListBox_PSU.ValueChangedFcn = createCallbackFcn(app, @SetControlOwnerListBox_PSUValueChanged, true);
            app.SetControlOwnerListBox_PSU.Enable = 'off';
            app.SetControlOwnerListBox_PSU.Value = 'remote';
            app.StopBitsDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.StopBitsDropDownLabel.Enable = 'off';
            app.StopBitsDropDownLabel.Text = 'Stop Bits';
            app.StopBitsDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.StopBitsDropDown_PSU.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_PSU.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_PSU.Editable = 'on';
            app.StopBitsDropDown_PSU.Enable = 'off';
            app.StopBitsDropDown_PSU.Value = '1';
            app.LANCOMMPanel = uipanel(app.GridLayout4);
            app.LANCOMMPanel.Title = 'LAN COMM';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PSUDropDownLabel = uilabel(app.GridLayout6);
            app.PSUDropDownLabel.Text = 'PSU';
            app.PSUDropDown = uidropdown(app.GridLayout6);
            app.PSUDropDown.Items = {'Select ...', 'APM', 'BK Precision', ''};
            app.PSUDropDown.ItemsData = {'', 'apm', 'bk'};
            app.PSUDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUDropDownValueChanged, true);
            app.PSUDropDown.Tooltip = {'Select Device used as a power supply (PSU)'};
            app.PSUDropDown.Value = '';
            app.ConnectonMethodGrid_PSU = uigridlayout(app.GridLayout5);
            app.ConnectionmethodDropDownLabel = uilabel(app.ConnectonMethodGrid_PSU);
            app.ConnectionmethodDropDownLabel.Text = 'Connection method';
            app.ConnectionmethodDropDown_PSU = uidropdown(app.ConnectonMethodGrid_PSU);
            app.ConnectionmethodDropDown_PSU.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_PSU.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_PSU.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_PSUValueChanged, true);
            app.ConnectionmethodDropDown_PSU.Value = '';
            app.ConnectPSUButton = uibutton(app.ConnectonMethodGrid_PSU, 'state');
            app.ConnectPSUButton.ValueChangedFcn = createCallbackFcn(app, @ConnectPSUButtonValueChanged, true);
            app.ConnectPSUButton.Enable = 'off';
            app.ConnectPSUButton.Text = 'Connect PSU';
            app.SelfTestCheckBox_PSU = uicheckbox(app.ConnectonMethodGrid_PSU);
            app.SelfTestCheckBox_PSU.Text = 'Self Test';
            app.ElectronicLoadTab = uitab(app.TabGroup);
            app.ElectronicLoadTab.Title = 'Electronic Load';
            app.GridLayout3_2 = uigridlayout(app.ElectronicLoadTab);
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.SerialPortPanel_ELoad = uipanel(app.GridLayout4_2);
            app.SerialPortPanel_ELoad.Title = 'SERIAL PORT';
            app.SerialPortGrid_ELoad = uigridlayout(app.SerialPortPanel_ELoad);
            app.ClearAlarmCodesButton_ELoad = uibutton(app.SerialPortGrid_ELoad, 'push');
            app.ClearAlarmCodesButton_ELoad.ButtonPushedFcn = createCallbackFcn(app, @ClearAlarmCodesButton_ELoadPushed, true);
            app.ClearAlarmCodesButton_ELoad.Enable = 'off';
            app.ClearAlarmCodesButton_ELoad.Text = 'Clear Alarm Codes';
            app.TerminalOutputsButtonGroup_ELoad = uibuttongroup(app.SerialPortGrid_ELoad);
            app.TerminalOutputsButtonGroup_ELoad.SelectionChangedFcn = createCallbackFcn(app, @TerminalOutputsButtonGroup_ELoadSelectionChanged, true);
            app.TerminalOutputsButtonGroup_ELoad.Tooltip = {'Allow or disallow the PSU from transferring power via terminals'};
            app.TerminalOutputsButtonGroup_ELoad.Title = 'Terminal Outputs';
            app.ONButton_ELoad = uitogglebutton(app.TerminalOutputsButtonGroup_ELoad);
            app.ONButton_ELoad.Enable = 'off';
            app.ONButton_ELoad.Text = 'ON';
            app.OFFButton_ELoad = uitogglebutton(app.TerminalOutputsButtonGroup_ELoad);
            app.OFFButton_ELoad.Enable = 'off';
            app.OFFButton_ELoad.Text = 'OFF';
            app.OFFButton_ELoad.Value = true;
            app.GetDeviceDetailsButton_ELoad = uibutton(app.SerialPortGrid_ELoad, 'push');
            app.GetDeviceDetailsButton_ELoad.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceDetailsButton_ELoadPushed, true);
            app.GetDeviceDetailsButton_ELoad.Enable = 'off';
            app.GetDeviceDetailsButton_ELoad.Text = 'Get Device Details';
            app.COMPortDropDownLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.COMPortDropDownLabel_2.Enable = 'off';
            app.COMPortDropDownLabel_2.Text = 'COM Port';
            app.COMPortDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.COMPortDropDown_ELoad.Items = {'Select COM Port'};
            app.COMPortDropDown_ELoad.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDown_ELoadValueChanged, true);
            app.COMPortDropDown_ELoad.Enable = 'off';
            app.COMPortDropDown_ELoad.Value = 'Select COM Port';
            app.BaudRateDropDownLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.BaudRateDropDownLabel_2.Enable = 'off';
            app.BaudRateDropDownLabel_2.Text = 'Baud Rate';
            app.BaudRateDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.BaudRateDropDown_ELoad.Items = {'4800', '9600', '19200', '38400'};
            app.BaudRateDropDown_ELoad.Enable = 'off';
            app.BaudRateDropDown_ELoad.Value = '38400';
            app.TimeoutEditFieldLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.TimeoutEditFieldLabel_2.Enable = 'off';
            app.TimeoutEditFieldLabel_2.Text = 'Timeout';
            app.TimeoutEditField_ELoad = uieditfield(app.SerialPortGrid_ELoad, 'text');
            app.TimeoutEditField_ELoad.Enable = 'off';
            app.TimeoutEditField_ELoad.Tooltip = {'in Seconds'};
            app.TimeoutEditField_ELoad.Value = '10';
            app.ByteOrderDropDownLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.ByteOrderDropDownLabel_3.Enable = 'off';
            app.ByteOrderDropDownLabel_3.Text = 'Byte Order';
            app.ByteOrderDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.ByteOrderDropDown_ELoad.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_ELoad.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_ELoad.Enable = 'off';
            app.ByteOrderDropDown_ELoad.Value = 'big-endian';
            app.TerminatorLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.TerminatorLabel_3.Enable = 'off';
            app.TerminatorLabel_3.Text = 'Terminator';
            app.TerminatorDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.TerminatorDropDown_ELoad.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_ELoad.Enable = 'off';
            app.TerminatorDropDown_ELoad.Value = 'LF';
            app.ResponseTextAreaLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.ResponseTextAreaLabel_3.Enable = 'off';
            app.ResponseTextAreaLabel_3.Text = 'Response';
            app.ResponseTextArea_ELoad = uitextarea(app.SerialPortGrid_ELoad);
            app.ResponseTextArea_ELoad.Editable = 'off';
            app.ResponseTextArea_ELoad.Enable = 'off';
            app.SetControlOwnerListBoxLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.SetControlOwnerListBoxLabel_2.Enable = 'off';
            app.SetControlOwnerListBoxLabel_2.Text = 'Set Control Owner';
            app.SetControlOwnerListBox_ELoad = uilistbox(app.SerialPortGrid_ELoad);
            app.SetControlOwnerListBox_ELoad.Items = {'Software', 'Local Device'};
            app.SetControlOwnerListBox_ELoad.ItemsData = {'remote', 'local'};
            app.SetControlOwnerListBox_ELoad.ValueChangedFcn = createCallbackFcn(app, @SetControlOwnerListBox_ELoadValueChanged, true);
            app.SetControlOwnerListBox_ELoad.Enable = 'off';
            app.SetControlOwnerListBox_ELoad.Value = 'remote';
            app.StopBitsDropDownLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.StopBitsDropDownLabel_3.Enable = 'off';
            app.StopBitsDropDownLabel_3.Text = 'Stop Bits';
            app.StopBitsDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.StopBitsDropDown_ELoad.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_ELoad.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_ELoad.Editable = 'on';
            app.StopBitsDropDown_ELoad.Enable = 'off';
            app.StopBitsDropDown_ELoad.Value = '1';
            app.GPIBPanel = uipanel(app.GridLayout4_2);
            app.GPIBPanel.Title = 'GPIB';
            app.GridLayout5_2 = uigridlayout(app.GridLayout3_2);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.ELoadLabel = uilabel(app.GridLayout6_2);
            app.ELoadLabel.Text = 'E-Load';
            app.ELoadDropDown = uidropdown(app.GridLayout6_2);
            app.ELoadDropDown.Items = {'Select...', 'Array'};
            app.ELoadDropDown.ItemsData = {'', 'array'};
            app.ELoadDropDown.ValueChangedFcn = createCallbackFcn(app, @ELoadDropDownValueChanged, true);
            app.ELoadDropDown.Value = '';
            app.ConnectonMethodGrid_ELoad = uigridlayout(app.GridLayout5_2);
            app.ConnectionmethodDropDownLabel_2 = uilabel(app.ConnectonMethodGrid_ELoad);
            app.ConnectionmethodDropDownLabel_2.Text = 'Connection method';
            app.ConnectionmethodDropDown_ELoad = uidropdown(app.ConnectonMethodGrid_ELoad);
            app.ConnectionmethodDropDown_ELoad.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_ELoad.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_ELoad.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_ELoadValueChanged, true);
            app.ConnectionmethodDropDown_ELoad.Value = '';
            app.ConnectELoadButton = uibutton(app.ConnectonMethodGrid_ELoad, 'state');
            app.ConnectELoadButton.ValueChangedFcn = createCallbackFcn(app, @ConnectELoadButtonValueChanged, true);
            app.ConnectELoadButton.Enable = 'off';
            app.ConnectELoadButton.Text = 'Connect E-Load';
            app.SelfTestCheckBox_ELoad = uicheckbox(app.ConnectonMethodGrid_ELoad);
            app.SelfTestCheckBox_ELoad.Text = 'Self Test';
            app.TemperatureModuleTab = uitab(app.TabGroup);
            app.TemperatureModuleTab.Title = 'Temperature Module';
            app.BalancerTab = uitab(app.TabGroup);
            app.BalancerTab.Title = 'Balancer';
            app.RelayMCUTab = uitab(app.TabGroup);
            app.RelayMCUTab.Title = 'Relay MCU';
            app.ContextMenu = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.ContextMenu
            app.ResponseTextArea_EStop.ContextMenu = app.ContextMenu;
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.CellTreeContextMenu = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.CellTreeContextMenu
            app.Tree.ContextMenu = app.CellTreeContextMenu;
            app.AddCellPackMenu = uimenu(app.CellTreeContextMenu);
            app.AddCellPackMenu.Text = 'Add Cell / Pack';
            app.ExpandMenu = uimenu(app.CellTreeContextMenu);
            app.ExpandMenu.Separator = 'on';
            app.ExpandMenu.Text = 'Expand';
            app.ExpandMenu_2 = uimenu(app.ExpandMenu);
            app.ExpandMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExpandMenu_2Selected, true);
            app.ExpandMenu_2.Text = 'Expand';
            app.ExpandAllMenu = uimenu(app.ExpandMenu);
            app.ExpandAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpandAllMenuSelected, true);
            app.ExpandAllMenu.Text = 'Expand All';
            app.CollapseMenu = uimenu(app.CellTreeContextMenu);
            app.CollapseMenu.Text = 'Collapse';
            app.CollapseMenu_2 = uimenu(app.CollapseMenu);
            app.CollapseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CollapseMenu_2Selected, true);
            app.CollapseMenu_2.Text = 'Collapse';
            app.CollapseAllMenu = uimenu(app.CollapseMenu);
            app.CollapseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CollapseAllMenuSelected, true);
            app.CollapseAllMenu.Text = 'Collapse All';
            app.DeselectAllMenu = uimenu(app.CellTreeContextMenu);
            app.DeselectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectAllMenuSelected, true);
            app.DeselectAllMenu.Separator = 'on';
            app.DeselectAllMenu.Accelerator = 'A';
            app.DeselectAllMenu.Text = 'Deselect All';
            app.CellDeleteContextMenu = uicontextmenu(app.devConfigsUIFigure);
            app.DeleteCellPackMenu = uimenu(app.CellDeleteContextMenu);
            app.DeleteCellPackMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellsButtonPushed, true);
            app.DeleteCellPackMenu.Text = 'Delete Cell / Pack';
            % Show the figure after all components are created
            app.devConfigsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceConfigs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.devConfigsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.devConfigsUIFigure)
        end
    end
end